classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        OFFButton             matlab.ui.control.Button
        PausaButton           matlab.ui.control.StateButton
        InicioButton          matlab.ui.control.Button
        OrigenButton          matlab.ui.control.Button
        ReconocimientoButton  matlab.ui.control.Button
        CONTROLDEROBOTPHANTOMXPINCHERLabel  matlab.ui.control.Label
        ONButton              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)
            picknplace;
        end

        % Button pushed function: OrigenButton
        function OrigenButtonPushed(app, event)
            Q = [0 0 0 0];
            set_pos(pub1,msg1,Q);
            set_pos(pub2,msg2,Q);
        end

        % Value changed function: PausaButton
        function PausaButtonValueChanged(app, event)
            while app.PausaButton.Value
            end
        end

        % Button pushed function: OFFButton
        function OFFButtonPushed(app, event)
            Q1 = get_st(sub1)
            Q2 = get_st(sub2)
            set_pos(pub1,msg1,Q1);
            set_pos(pub2,msg2,Q2);
            rosshutdown;
        end

        % Button pushed function: ReconocimientoButton
        function ReconocimientoButtonPushed(app, event)
            reconocimiento;
        end

        % Button pushed function: ONButton
        function ONButtonPushed(app, event)
            rosinit;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Posición gripper 1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-30 30];
            app.UIAxes.YLim = [-30 30];
            app.UIAxes.XTick = [-30 -15 0 15 30];
            app.UIAxes.XTickLabel = {'-30'; '-15'; '0'; '15'; '30'};
            app.UIAxes.YTick = [-30 -15 0 15 30];
            app.UIAxes.YTickLabel = {'-30'; '-15'; '0'; '15'; '30'};
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Posición gripper 2')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2.XLim = [-30 30];
            app.UIAxes2.YLim = [-30 30];
            app.UIAxes2.XTick = [-30 -15 0 15 30];
            app.UIAxes2.YTick = [-30 -15 0 15 30];
            app.OFFButton = uibutton(app.UIFigure, 'push');
            app.OFFButton.ButtonPushedFcn = createCallbackFcn(app, @OFFButtonPushed, true);
            app.OFFButton.Text = 'OFF';
            app.PausaButton = uibutton(app.UIFigure, 'state');
            app.PausaButton.ValueChangedFcn = createCallbackFcn(app, @PausaButtonValueChanged, true);
            app.PausaButton.Text = 'Pausa';
            app.InicioButton = uibutton(app.UIFigure, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Text = 'Inicio';
            app.OrigenButton = uibutton(app.UIFigure, 'push');
            app.OrigenButton.ButtonPushedFcn = createCallbackFcn(app, @OrigenButtonPushed, true);
            app.OrigenButton.Text = 'Origen';
            app.ReconocimientoButton = uibutton(app.UIFigure, 'push');
            app.ReconocimientoButton.ButtonPushedFcn = createCallbackFcn(app, @ReconocimientoButtonPushed, true);
            app.ReconocimientoButton.Text = 'Reconocimiento';
            app.CONTROLDEROBOTPHANTOMXPINCHERLabel = uilabel(app.UIFigure);
            app.CONTROLDEROBOTPHANTOMXPINCHERLabel.Text = 'CONTROL DE ROBOT PHANTOM X PINCHER';
            app.ONButton = uibutton(app.UIFigure, 'push');
            app.ONButton.ButtonPushedFcn = createCallbackFcn(app, @ONButtonPushed, true);
            app.ONButton.Text = 'ON';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end