classdef GUI1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        NutsandboltssortingmechanismLabel  matlab.ui.control.Label
        InputimageLabel          matlab.ui.control.Label
        NutsandboltsLabel        matlab.ui.control.Label
        ImagepathEditFieldLabel  matlab.ui.control.Label
        ImagepathEditField       matlab.ui.control.EditField
        UITable                  matlab.ui.control.Table
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        LoadDataButton           matlab.ui.control.Button
        GetElementsButton        matlab.ui.control.Button
        UITable_2                matlab.ui.control.Table
        SendDataButton           matlab.ui.control.Button
        ONButton                 matlab.ui.control.Button
        OFFButton                matlab.ui.control.Button
        BeginButton              matlab.ui.control.Button
        HomeButton               matlab.ui.control.Button
        UITable_3                matlab.ui.control.Table
        UITable_4                matlab.ui.control.Table
        PauseButton              matlab.ui.control.StateButton
    end


    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
        
            global value;
            
            % Remove title, axis labels, and tick labels
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imshow(value, 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
        end

        % Value changed function: ImagepathEditField
        function ImagepathEditFieldValueChanged(app, event)
            global value;
            value = app.ImagepathEditField.Value;
        end

        % Button pushed function: GetElementsButton
        function GetElementsButtonPushed(app, event)
            global value;
            global nuts;
            global bolts;
            global cB;
            global cN;
            razon=10;
            I=imread(value);
            Igray= rgb2gray(I);
            X = imadjust(Igray);
            % Threshold image - adaptive threshold
            BW = imbinarize(X, 'adaptive', 'Sensitivity', 0.120000, 'ForegroundPolarity', 'dark');
            % Invert mask
            BW = imcomplement(BW);
            % Open mask with octagon
            radius = 9;
            se = strel('octagon', radius);
            BW = imopen(BW, se);
            % Dilate mask with octagon
            radius = 36;
            se = strel('octagon', radius);
            BW = imdilate(BW, se);
            
            % Close mask with octagon
            radius = 3;
            se = strel('octagon', radius);
            BW = imclose(BW, se);
            
            % Create masked image.
            maskedImage = X;
            maskedImage(~BW) = 0;
            [regions, numPixelRegions]= bwlabel(BW);
            stats = regionprops(regions, 'all');
            Area=[];
            for k=1:length(stats)
                %text(stats(k).Centroid(1), stats(k).Centroid(2),sprintf('%04d',stats(k).Area), 'Hor', 'Center', 'Vert', 'middle');
                Area = [Area, stats(k).Area];
            end
           
            maxArea=[80000 150000];
            minArea=[20000 100000];
            partNames = {'nut', 'bolt'};
            partColors = {'magenta'; 'green'};
            Iparts = I;
            for k=1:2
                idx = Area  > minArea(k) & Area < maxArea(k);
                Iparts = insertObjectAnnotation(Iparts, 'rectangle', vertcat(stats(idx).BoundingBox), partNames{k}, 'Color', partColors{k});
            end
            bolts = sum(idx(:) == 1);
            nuts = sum(idx(:) == 0);
            app.UITable.Data = bolts;
            app.UITable_2.Data = nuts;
            cB=[];
            cN=[];
            for i=1:length(idx)
                if idx(i)==1
                    cB=[cB; stats(i).Centroid(1)/razon, stats(i).Centroid(2)/razon];
                else 
                    cN=[cN; stats(i).Centroid(1)/razon, stats(i).Centroid(2)/razon];
                end
            end
            
            % Remove title, axis labels, and tick labels
            title(app.UIAxes_2, []);
            xlabel(app.UIAxes_2, []);
            ylabel(app.UIAxes_2, []);
            app.UIAxes_2.XAxis.TickLabels = {};
            app.UIAxes_2.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imshow(Iparts, 'Parent', app.UIAxes_2, ...
                'XData', [1 app.UIAxes_2.Position(3)], ...
                'YData', [1 app.UIAxes_2.Position(4)]);
            % Set limits of axes
            app.UIAxes_2.XLim = [0 I.XData(2)];
            app.UIAxes_2.YLim = [0 I.YData(2)];
        end

        % Button pushed function: ONButton
        function ONButtonPushed(app, event)
            rosinit;
        end

        % Button pushed function: OFFButton
        function OFFButtonPushed(app, event)
            [pub1,msg1] = phantom_publisher('/phantom1');
            [pub2,msg2] = phantom_publisher('/phantom2');
            sub1=rossubscriber('/phantom1/joint_states');
            sub2=rossubscriber('/phantom2/joint_states');
            Q1 = get_st(sub1)
            Q2 = get_st(sub2)
            set_pos(pub1,msg1,Q1);
            set_pos(pub2,msg2,Q2);
            rosshutdown;
        end

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
            global nuts;
            global bolts;
            global cB;
            global cN;
            rosshutdown;
            rosinit;
            [pub1,msg1] = phantom_publisher('/phantom1');
            [pub2,msg2] = phantom_publisher('/phantom2');
            sub1=rossubscriber('/phantom1/joint_states');
            sub2=rossubscriber('/phantom2/joint_states');
            counter=0;
            QEnd = [100,pi/2,200,pi/4];
            PEnd = [1.372,0.2445,0.7;
                    1.372,-0.7,1.4;
                    2.032,0.2445,0.7;
                    2.032,-0.7,1.4;
                    2.250,0.0245,0.7;
                    2.250,-0.7,1.4];
            for i=1:length(cB)+length(cN)
                app.UITable_3.Data = bolts-counter;
                app.UITable_4.Data = nuts-counter;
                move1(cB(i,1),cB(2,i),PEnd(i,1),PEnd(i,2),pub1,sub1,msg1);
                move1(cN(i,1),cN(2,i),PEnd(i,1),PEnd(i,2),pub2,sub2,msg2);
                counter=counter+1;
                while app.PauseButton.Value == true
                    pause;
                end
            end
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            Q = [0 0 0 0];
            set_pos(pub1,msg1,Q);
            set_pos(pub2,msg2,Q);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NutsandboltssortingmechanismLabel = uilabel(app.UIFigure);
            app.NutsandboltssortingmechanismLabel.Text = 'Nuts and bolts sorting mechanism';
            app.InputimageLabel = uilabel(app.UIFigure);
            app.InputimageLabel.Text = 'Input image';
            app.NutsandboltsLabel = uilabel(app.UIFigure);
            app.NutsandboltsLabel.Text = 'Nuts and bolts';
            app.ImagepathEditFieldLabel = uilabel(app.UIFigure);
            app.ImagepathEditFieldLabel.Text = 'Image path';
            app.ImagepathEditField = uieditfield(app.UIFigure, 'text');
            app.ImagepathEditField.ValueChangedFcn = createCallbackFcn(app, @ImagepathEditFieldValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Number of Bolts'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.GetElementsButton = uibutton(app.UIFigure, 'push');
            app.GetElementsButton.ButtonPushedFcn = createCallbackFcn(app, @GetElementsButtonPushed, true);
            app.GetElementsButton.Text = 'Get Elements';
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Number of Nuts'};
            app.UITable_2.RowName = {};
            app.SendDataButton = uibutton(app.UIFigure, 'push');
            app.SendDataButton.Text = 'Send Data';
            app.ONButton = uibutton(app.UIFigure, 'push');
            app.ONButton.ButtonPushedFcn = createCallbackFcn(app, @ONButtonPushed, true);
            app.ONButton.Text = 'ON';
            app.OFFButton = uibutton(app.UIFigure, 'push');
            app.OFFButton.ButtonPushedFcn = createCallbackFcn(app, @OFFButtonPushed, true);
            app.OFFButton.Text = 'OFF';
            app.BeginButton = uibutton(app.UIFigure, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.UITable_3 = uitable(app.UIFigure);
            app.UITable_3.ColumnName = {'Missing Bolts'};
            app.UITable_3.RowName = {};
            app.UITable_4 = uitable(app.UIFigure);
            app.UITable_4.ColumnName = {'Missing Nuts'};
            app.UITable_4.RowName = {};
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.Text = 'Pause';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end